classdef mojoxlab_v0_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MoJoXlabv01UIFigure             matlab.ui.Figure
        GetStaticCalibrationDataButton  matlab.ui.control.Button
        GetActivityDataButton           matlab.ui.control.Button
        SamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyHzEditField    matlab.ui.control.NumericEditField
        StaticCalibrationFilepathField  matlab.ui.control.EditField
        ActivityFilepathField           matlab.ui.control.EditField
        JointAnglesResultsFilepathField  matlab.ui.control.EditField
        CalculateJointAnglesButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        OutputFilename = sprintf('jointangles_%s.csv', datestr(now, 'ddmmyyyy_HHMMSS')); % Output filename and type
    end


    methods (Access = private)

        % Button pushed function: GetStaticCalibrationDataButton
        function GetStaticCalibrationDataButtonPushed(app, event)
            [file,path] = uigetfile({'*.csv','Comma Separated Values (*.csv)';
                '*.tsv','Tab Separated Values (*.tsv)'; ...
                '*.txt','Text Files (*.txt)'}, ...
                'Select a Static Calibration File');
            if isequal(file,0)
                return
            else
                app.StaticCalibrationFilepathField.Value = fullfile(path,file);

            end
        end

        % Button pushed function: GetActivityDataButton
        function GetActivityDataButtonPushed(app, event)
            [file,path] = uigetfile({'*.csv','Comma Separated Values (*.csv)';
                '*.tsv','Tab Separated Values (*.tsv)'; ...
                '*.txt','Text Files (*.txt)'}, ...
                'Select a Static Calibration File');
            if isequal(file,0)
                return
            else
                app.ActivityFilepathField.Value = fullfile(path,file);
            end
        end

        % Button pushed function: CalculateJointAnglesButton
        function CalculateJointAnglesButtonPushed(app, event)
            app.JointAnglesResultsFilepathField.Value = fullfile(strcat(pwd,'/',app.OutputFilename));
            %% Input Data
            staticQuaternions = csvread(app.StaticCalibrationFilepathField.Value);
            walkingQuaternions = csvread(app.ActivityFilepathField.Value);
            samplingFrequency = app.SamplingFrequencyHzEditField.Value;
            
            
            staticPelvis_Orientation{1} = staticQuaternions(:,1:4);
            staticThigh_Orientation{1} = staticQuaternions(:,17:20);
            staticThigh_Orientation{2} = staticQuaternions(:,5:8);
            staticShank_Orientation{1} = staticQuaternions(:,21:24);
            staticShank_Orientation{2} = staticQuaternions(:,9:12);
            staticFoot_Orientation{1} = staticQuaternions(:,25:28);
            staticFoot_Orientation{2} = staticQuaternions(:,13:16);
            
            walkingPelvis_Orientation{1} = walkingQuaternions(:,1:4);
            walkingThigh_Orientation{1} = walkingQuaternions(:,17:20);
            walkingThigh_Orientation{2} = walkingQuaternions(:,5:8);
            walkingShank_Orientation{1} = walkingQuaternions(:,21:24);
            walkingShank_Orientation{2} = walkingQuaternions(:,9:12);
            walkingFoot_Orientation{1} = walkingQuaternions(:,25:28);
            walkingFoot_Orientation{2} = walkingQuaternions(:,13:16);
            
            %% Filter data
            
            Wn=samplingFrequency/2; % MVN sampling rate was 60 Hz
            cutoff=6;
            [b,a] = butter(2,cutoff/Wn,'low');
            
            for side=1:2 %1:Left ; 2: Right
                
                static_pelvis_standing = filtfilt(b,a,(staticPelvis_Orientation{1,1}));
                static_rthigh_standing{side} = filtfilt(b,a,(staticThigh_Orientation{1,side}));
                static_rshank_standing {side} = filtfilt(b,a,(staticShank_Orientation{1,side}));
                static_rfoot_standing {side}  = filtfilt(b,a,(staticFoot_Orientation{1,side}));
                
                walking_pelvis_standing = filtfilt(b,a,(walkingPelvis_Orientation{1,1}));
                walking_rthigh_standing{side} = filtfilt(b,a,(walkingThigh_Orientation{1,side}));
                walking_rshank_standing {side} = filtfilt(b,a,(walkingShank_Orientation{1,side}));
                walking_rfoot_standing {side}  = filtfilt(b,a,(walkingFoot_Orientation{1,side}));
            end
            
            %% Calculating Joint Angles
            d1 = static_pelvis_standing;
            d2 = static_rthigh_standing;
            d3 = static_rshank_standing;
            d4 = static_rfoot_standing;
            
            d5 = walking_pelvis_standing;
            d6 = walking_rthigh_standing;
            d7 = walking_rshank_standing;
            d8 = walking_rfoot_standing;
            
            for side=1:2
                
                [q_imu_pv_bf,q_imu_th_bf,q_imu_sh_bf,q_imu_ft_bf] = StandingCalib_IMU_MA(d1,d2{1,side},d3{1,side},d4{1,side},5,50);
                
                [Ahip_angle{side},Aknee_angle{side},Aankle_angle{side}]= JointAngles_IMU_MA(q_imu_pv_bf,q_imu_th_bf,q_imu_sh_bf,q_imu_ft_bf,d5,d6{1,side},d7{1,side},d8{1,side},1);
                
                %clearvars -except d1 d2 d3 d4 d5 d6 d7 d8 Ahip_angle Aknee_angle Aankle_angle app.OutputFilename
            end
            
            %% output data as csv file
            
            M = [Aankle_angle{1}, Aankle_angle{2}, Ahip_angle{1}, Ahip_angle{2}, Aknee_angle{1}, Aknee_angle{2}];
            %filename = sprintf('jointangles_%s.csv', datestr(now, 'ddmmyyyy_HHMMSS'));
            
            %app.JointAnglesResultsFilepathField.Value = 'asfsadfsf';
            %disp(app.JointAnglesResultsFilepathField.Value)
%             app.Label.Text = datestr(now, 'ddmmyyyy_HHMMSS');
%             disp(app.Label.Text)
            csvwrite(app.OutputFilename,M);
          
            %% Plotting for testing purposes
            plot(Aknee_angle{1,2}(:,1));hold on;plot(Aknee_angle{1,1}(:,1),'r')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MoJoXlabv01UIFigure = uifigure;
            app.MoJoXlabv01UIFigure.Name = 'MoJoXlab v0.1';
            app.GetStaticCalibrationDataButton = uibutton(app.MoJoXlabv01UIFigure, 'push');
            app.GetStaticCalibrationDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetStaticCalibrationDataButtonPushed, true);
            app.GetStaticCalibrationDataButton.Text = 'Get Static Calibration Data';
            app.GetActivityDataButton = uibutton(app.MoJoXlabv01UIFigure, 'push');
            app.GetActivityDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetActivityDataButtonPushed, true);
            app.GetActivityDataButton.Text = 'Get Activity Data';
            app.SamplingFrequencyHzEditFieldLabel = uilabel(app.MoJoXlabv01UIFigure);
            app.SamplingFrequencyHzEditFieldLabel.Text = 'Sampling Frequency (Hz)';
            app.SamplingFrequencyHzEditField = uieditfield(app.MoJoXlabv01UIFigure, 'numeric');
            app.StaticCalibrationFilepathField = uieditfield(app.MoJoXlabv01UIFigure, 'text');
            app.StaticCalibrationFilepathField.Editable = 'off';
            app.ActivityFilepathField = uieditfield(app.MoJoXlabv01UIFigure, 'text');
            app.ActivityFilepathField.Editable = 'off';
            app.JointAnglesResultsFilepathField = uieditfield(app.MoJoXlabv01UIFigure, 'text');
            app.JointAnglesResultsFilepathField.Editable = 'off';
            app.CalculateJointAnglesButton = uibutton(app.MoJoXlabv01UIFigure, 'push');
            app.CalculateJointAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateJointAnglesButtonPushed, true);
            app.CalculateJointAnglesButton.Text = 'Calculate Joint Angles';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mojoxlab_v0_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MoJoXlabv01UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MoJoXlabv01UIFigure)
        end
    end
end