classdef mojoxlab_v0_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MoJoXlabv01UIFigure             matlab.ui.Figure
        GetStaticCalibrationDataButton  matlab.ui.control.Button
        GetActivityDataButton           matlab.ui.control.Button
        SamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyHzEditField    matlab.ui.control.NumericEditField
        StaticCalibrationFilepathField  matlab.ui.control.EditField
        ActivityFilepathField           matlab.ui.control.EditField
        JointAnglesResultsFilepathField  matlab.ui.control.EditField
        CalculateJointAnglesButton      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ButtonGroup                     matlab.ui.container.ButtonGroup
        AnkleLeftSagittalButton         matlab.ui.control.RadioButton
        AnkleLeftFrontalButton          matlab.ui.control.RadioButton
        AnkleRightSagittalButton        matlab.ui.control.RadioButton
        AnkleRightFrontalButton         matlab.ui.control.RadioButton
        HipLeftSagittalButton           matlab.ui.control.RadioButton
        HipLeftFrontalButton            matlab.ui.control.RadioButton
        HipLeftTransverseButton         matlab.ui.control.RadioButton
        HipRightSagittalButton          matlab.ui.control.RadioButton
        HipRightFrontalButton           matlab.ui.control.RadioButton
        HipRightTransverseButton        matlab.ui.control.RadioButton
        KneeLeftSagittalButton          matlab.ui.control.RadioButton
        KneeLeftFrontalButton           matlab.ui.control.RadioButton
        KneeLeftTransverseButton        matlab.ui.control.RadioButton
        KneeRightSagittalButton         matlab.ui.control.RadioButton
        KneeRightFrontalButton          matlab.ui.control.RadioButton
        KneeRightTransverseButton       matlab.ui.control.RadioButton
        HelpTextAreaLabel               matlab.ui.control.Label
        HelpTextArea                    matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        OutputFilename = sprintf('/jointangles_%s.csv', datestr(now, 'ddmmyyyy_HHMMSS')); % Output filename and type
        M;
    end


    methods (Access = private)

        % Button pushed function: GetStaticCalibrationDataButton
        function GetStaticCalibrationDataButtonPushed(app, event)
            [file,path] = uigetfile({'*.csv','Comma Separated Values (*.csv)';
                '*.tsv','Tab Separated Values (*.tsv)'; ...
                '*.txt','Text Files (*.txt)'}, ...
                'Select a Static Calibration File');
            if isequal(file,0)
                return
            else
                app.StaticCalibrationFilepathField.Value = fullfile(path,file);

            end
        end

        % Button pushed function: GetActivityDataButton
        function GetActivityDataButtonPushed(app, event)
            [file,path] = uigetfile({'*.csv','Comma Separated Values (*.csv)';
                '*.tsv','Tab Separated Values (*.tsv)'; ...
                '*.txt','Text Files (*.txt)'}, ...
                'Select a Static Calibration File');
            if isequal(file,0)
                return
            else
                app.ActivityFilepathField.Value = fullfile(path,file);
            end
        end

        % Button pushed function: CalculateJointAnglesButton
        function CalculateJointAnglesButtonPushed(app, event)
            selpath = uigetdir;
            app.JointAnglesResultsFilepathField.Value = fullfile(strcat(selpath, app.OutputFilename));
            %% Input Data
            staticQuaternions = csvread(app.StaticCalibrationFilepathField.Value,1,0);
            walkingQuaternions = csvread(app.ActivityFilepathField.Value,1,0);
            samplingFrequency = app.SamplingFrequencyHzEditField.Value;
            
            
            staticPelvis_Orientation{1} = staticQuaternions(:,1:4);
            staticThigh_Orientation{1} = staticQuaternions(:,17:20);
            staticThigh_Orientation{2} = staticQuaternions(:,5:8);
            staticShank_Orientation{1} = staticQuaternions(:,21:24);
            staticShank_Orientation{2} = staticQuaternions(:,9:12);
            staticFoot_Orientation{1} = staticQuaternions(:,25:28);
            staticFoot_Orientation{2} = staticQuaternions(:,13:16);
            
            walkingPelvis_Orientation{1} = walkingQuaternions(:,1:4);
            walkingThigh_Orientation{1} = walkingQuaternions(:,17:20);
            walkingThigh_Orientation{2} = walkingQuaternions(:,5:8);
            walkingShank_Orientation{1} = walkingQuaternions(:,21:24);
            walkingShank_Orientation{2} = walkingQuaternions(:,9:12);
            walkingFoot_Orientation{1} = walkingQuaternions(:,25:28);
            walkingFoot_Orientation{2} = walkingQuaternions(:,13:16);
            
            %% Filter data
            
            Wn=samplingFrequency/2; % MVN sampling rate was 60 Hz
            cutoff=6;
            [b,a] = butter(2,cutoff/Wn,'low');
            
            for side=1:2 %1:Left ; 2: Right
                
                static_pelvis_standing = filtfilt(b,a,(staticPelvis_Orientation{1,1}));
                static_rthigh_standing{side} = filtfilt(b,a,(staticThigh_Orientation{1,side}));
                static_rshank_standing {side} = filtfilt(b,a,(staticShank_Orientation{1,side}));
                static_rfoot_standing {side}  = filtfilt(b,a,(staticFoot_Orientation{1,side}));
                
                walking_pelvis_standing = filtfilt(b,a,(walkingPelvis_Orientation{1,1}));
                walking_rthigh_standing{side} = filtfilt(b,a,(walkingThigh_Orientation{1,side}));
                walking_rshank_standing {side} = filtfilt(b,a,(walkingShank_Orientation{1,side}));
                walking_rfoot_standing {side}  = filtfilt(b,a,(walkingFoot_Orientation{1,side}));
            end
            
            %% Calculating Joint Angles
            d1 = static_pelvis_standing;
            d2 = static_rthigh_standing;
            d3 = static_rshank_standing;
            d4 = static_rfoot_standing;
            
            d5 = walking_pelvis_standing;
            d6 = walking_rthigh_standing;
            d7 = walking_rshank_standing;
            d8 = walking_rfoot_standing;
            
            for side=1:2
                
                [q_imu_pv_bf,q_imu_th_bf,q_imu_sh_bf,q_imu_ft_bf] = StandingCalib_IMU_MA(d1,d2{1,side},d3{1,side},d4{1,side},5,50);
                
                [Ahip_angle{side},Aknee_angle{side},Aankle_angle{side}]= JointAngles_IMU_MA(q_imu_pv_bf,q_imu_th_bf,q_imu_sh_bf,q_imu_ft_bf,d5,d6{1,side},d7{1,side},d8{1,side},1);
                
                %clearvars -except d1 d2 d3 d4 d5 d6 d7 d8 Ahip_angle Aknee_angle Aankle_angle app.OutputFilename
            end
            
            %% output data as csv file
            
            app.M = [Aankle_angle{1}, Aankle_angle{2}, Ahip_angle{1}, Ahip_angle{2}, Aknee_angle{1}, Aknee_angle{2}];
            %filename = sprintf('jointangles_%s.csv', datestr(now, 'ddmmyyyy_HHMMSS'));
            
            %app.JointAnglesResultsFilepathField.Value = 'asfsadfsf';
            %disp(app.JointAnglesResultsFilepathField.Value)
            %             app.Label.Text = datestr(now, 'ddmmyyyy_HHMMSS');
            %             disp(app.Label.Text)
            %csvwrite(app.JointAnglesResultsFilepathField.Value,app.M);
            T = array2table(app.M,'VariableNames',{'AnkleLeftSagittal', 'AnkleLeftFrontal', 'AnkleRightSagittal', 'AnkleRightFrontal', ...
                'HipLeftSagittal', 'HipLeftFrontal', 'HipLeftTransverse', 'HipRightSagittal', 'HipRIghtFrontal', 'HipRightTransverse', ...
                'KneeLeftSagittal', 'KneeLeftFrontal', 'KneeLeftTransverse', 'KneeRightSagittal', 'KneeRightFrontal', 'KneeRightTransverse'});
            writetable(T, app.JointAnglesResultsFilepathField.Value);
            
            
            %% Plotting for testing purposes
            %plot(Aknee_angle{1,2}(:,1));hold on;plot(Aknee_angle{1,1}(:,1),'r')
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if app.AnkleLeftSagittalButton.Value == 1
                plot(app.UIAxes, app.M(:,1), 'LineWidth',2);
                title(app.UIAxes,'Joint Angle Waveform - Ankle Left Sagittal')
            elseif app.AnkleLeftFrontalButton.Value == 1
                plot(app.UIAxes, app.M(:,2), 'LineWidth',2);
                title(app.UIAxes,'Joint Angle Waveform - Ankle Left Frontal')
            elseif app.AnkleRightSagittalButton.Value == 1
                plot(app.UIAxes, app.M(:,3), 'LineWidth',2);
                title(app.UIAxes,'Joint Angle Waveform - Ankle Right Sagittal')
            elseif app.AnkleRightFrontalButton.Value == 1
                plot(app.UIAxes, app.M(:,4), 'LineWidth',2);
                title(app.UIAxes,'Joint Angle Waveform - Ankle Right Frontal')
            elseif app.HipLeftSagittalButton.Value == 1
                plot(app.UIAxes, app.M(:,5), 'LineWidth',2);
                title(app.UIAxes,'Joint Angle Waveform - Hip Left Sagittal')
            elseif app.HipLeftFrontalButton.Value == 1
                plot(app.UIAxes, app.M(:,6), 'LineWidth',2);
                title(app.UIAxes,'Joint Angle Waveform - Hip Left Frontal')
            elseif app.HipLeftTransverseButton.Value == 1
                plot(app.UIAxes, app.M(:,7), 'LineWidth',2);
                title(app.UIAxes,'Joint Angle Waveform - Hip Left Transverse')
            elseif app.HipRightSagittalButton.Value == 1
                plot(app.UIAxes, app.M(:,8), 'LineWidth',2);
                title(app.UIAxes,'Joint Angle Waveform - Hip Right Sagittal')
            elseif app.HipRightFrontalButton.Value == 1
                plot(app.UIAxes, app.M(:,9), 'LineWidth',2);
                title(app.UIAxes,'Joint Angle Waveform - Hip Right Frontal')
            elseif app.HipRightTransverseButton.Value == 1
                plot(app.UIAxes, app.M(:,10), 'LineWidth',2);
                title(app.UIAxes,'Joint Angle Waveform - Hip Right Transverse')
            elseif app.KneeLeftSagittalButton.Value == 1
                plot(app.UIAxes, app.M(:,11), 'LineWidth',2);
                title(app.UIAxes,'Joint Angle Waveform - Knee Left Sagittal')
            elseif app.KneeLeftFrontalButton.Value == 1
                plot(app.UIAxes, app.M(:,12), 'LineWidth',2);
                title(app.UIAxes,'Joint Angle Waveform - Knee Left Frontal')
            elseif app.KneeLeftTransverseButton.Value == 1
                plot(app.UIAxes, app.M(:,13), 'LineWidth',2);
                title(app.UIAxes,'Joint Angle Waveform - Knee Left Transverse')
            elseif app.KneeRightSagittalButton.Value == 1
                plot(app.UIAxes, app.M(:,14), 'LineWidth',2);
                title(app.UIAxes,'Joint Angle Waveform - Knee Right Sagittal')
            elseif app.KneeRightFrontalButton.Value == 1
                plot(app.UIAxes, app.M(:,15), 'LineWidth',2);
                title(app.UIAxes,'Joint Angle Waveform - Knee Right Frontal')
            elseif app.KneeRightTransverseButton.Value == 1
                plot(app.UIAxes, app.M(:,16), 'LineWidth',2);
                title(app.UIAxes,'Joint Angle Waveform - Knee Right Transverse')
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MoJoXlabv01UIFigure = uifigure;
            app.MoJoXlabv01UIFigure.Name = 'MoJoXlab v0.1';
            app.GetStaticCalibrationDataButton = uibutton(app.MoJoXlabv01UIFigure, 'push');
            app.GetStaticCalibrationDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetStaticCalibrationDataButtonPushed, true);
            app.GetStaticCalibrationDataButton.Text = 'Get Static Calibration Data';
            app.GetActivityDataButton = uibutton(app.MoJoXlabv01UIFigure, 'push');
            app.GetActivityDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetActivityDataButtonPushed, true);
            app.GetActivityDataButton.Text = 'Get Activity Data';
            app.SamplingFrequencyHzEditFieldLabel = uilabel(app.MoJoXlabv01UIFigure);
            app.SamplingFrequencyHzEditFieldLabel.Text = 'Sampling Frequency (Hz)';
            app.SamplingFrequencyHzEditField = uieditfield(app.MoJoXlabv01UIFigure, 'numeric');
            app.SamplingFrequencyHzEditField.Value = 60;
            app.StaticCalibrationFilepathField = uieditfield(app.MoJoXlabv01UIFigure, 'text');
            app.StaticCalibrationFilepathField.Editable = 'off';
            app.ActivityFilepathField = uieditfield(app.MoJoXlabv01UIFigure, 'text');
            app.ActivityFilepathField.Editable = 'off';
            app.JointAnglesResultsFilepathField = uieditfield(app.MoJoXlabv01UIFigure, 'text');
            app.JointAnglesResultsFilepathField.Editable = 'off';
            app.CalculateJointAnglesButton = uibutton(app.MoJoXlabv01UIFigure, 'push');
            app.CalculateJointAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateJointAnglesButtonPushed, true);
            app.CalculateJointAnglesButton.Text = 'Calculate Joint Angles';
            app.UIAxes = uiaxes(app.MoJoXlabv01UIFigure);
            xlabel(app.UIAxes, 'Samples -->')
            ylabel(app.UIAxes, 'Angle in degrees')
            app.ButtonGroup = uibuttongroup(app.MoJoXlabv01UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.AnkleLeftSagittalButton = uiradiobutton(app.ButtonGroup);
            app.AnkleLeftSagittalButton.Text = 'Ankle Left Sagittal';
            app.AnkleLeftSagittalButton.Value = true;
            app.AnkleLeftFrontalButton = uiradiobutton(app.ButtonGroup);
            app.AnkleLeftFrontalButton.Text = 'Ankle Left Frontal';
            app.AnkleRightSagittalButton = uiradiobutton(app.ButtonGroup);
            app.AnkleRightSagittalButton.Text = 'Ankle Right Sagittal';
            app.AnkleRightFrontalButton = uiradiobutton(app.ButtonGroup);
            app.AnkleRightFrontalButton.Text = 'Ankle Right Frontal';
            app.HipLeftSagittalButton = uiradiobutton(app.ButtonGroup);
            app.HipLeftSagittalButton.Text = 'Hip Left Sagittal';
            app.HipLeftFrontalButton = uiradiobutton(app.ButtonGroup);
            app.HipLeftFrontalButton.Text = 'Hip Left Frontal';
            app.HipLeftTransverseButton = uiradiobutton(app.ButtonGroup);
            app.HipLeftTransverseButton.Text = 'Hip Left Transverse';
            app.HipRightSagittalButton = uiradiobutton(app.ButtonGroup);
            app.HipRightSagittalButton.Text = 'Hip Right Sagittal';
            app.HipRightFrontalButton = uiradiobutton(app.ButtonGroup);
            app.HipRightFrontalButton.Text = 'Hip Right Frontal';
            app.HipRightTransverseButton = uiradiobutton(app.ButtonGroup);
            app.HipRightTransverseButton.Text = 'Hip Right Transverse';
            app.KneeLeftSagittalButton = uiradiobutton(app.ButtonGroup);
            app.KneeLeftSagittalButton.Text = 'Knee Left Sagittal';
            app.KneeLeftFrontalButton = uiradiobutton(app.ButtonGroup);
            app.KneeLeftFrontalButton.Text = 'Knee Left Frontal';
            app.KneeLeftTransverseButton = uiradiobutton(app.ButtonGroup);
            app.KneeLeftTransverseButton.Text = 'Knee Left Transverse';
            app.KneeRightSagittalButton = uiradiobutton(app.ButtonGroup);
            app.KneeRightSagittalButton.Text = 'Knee Right Sagittal';
            app.KneeRightFrontalButton = uiradiobutton(app.ButtonGroup);
            app.KneeRightFrontalButton.Text = 'Knee Right Frontal';
            app.KneeRightTransverseButton = uiradiobutton(app.ButtonGroup);
            app.KneeRightTransverseButton.Text = 'Knee Right Transverse';
            app.HelpTextAreaLabel = uilabel(app.MoJoXlabv01UIFigure);
            app.HelpTextAreaLabel.Text = 'Help';
            app.HelpTextArea = uitextarea(app.MoJoXlabv01UIFigure);
            app.HelpTextArea.Value = {'Get Static Calibration Data:'; 'Use this button to select the static calibration data file. A dialog box will open to allow you to select the calibration data file. Be careful as sometimes the dialog box opens behind the app and becomes difficult to see. After you have selected the calibration data file. The filepath and filename is shown on the adjacent text field. '; '[sample data is provided with the main zip folder with filename: sample_StaticCalibrationDataFile.csv]'; ''; 'Get Activity Data:'; 'Use this button to select the dynamic activity file for example, walking, jumping or squatting activity data file. A dialog box will open to allow you to select the calibration data file. Be careful as sometimes the dialog box opens behind the app and becomes difficult to see. After you have selected the calibration data file. The filepath and filename is shown on the adjacent text field. '; '[sample data is provided with the same zip folder with filename: sample_ActivityDataFile.csv]'; ''; 'Sampling Frequency:'; 'Use this field to input the sampling frequency of the activity data in Hertz (Hz). [use 60 Hz for sample data]'; ''; 'Calculate Joint Angles:'; 'Use this button to calculate the joint angles. A dialog box will open that will allow you to select the output folder. Be careful as sometimes the dialog box opens behind the app and becomes difficult to see. The output data file will be saved in this folder. The filename is automatically generated and will be shown on the adjacent text field.'; ''; 'Joint Angle Waveforms:'; 'The plotting area shows the joing angles calculated in degrees, for either Ankle, Hip or Knee joints, showing left or right side, for either sagittal, frontal or transverse planes.'; ''; 'For Your Own Use:'; 'Input files should be in quaternions for 7 sensors with 28 columns of data in the following order: '; 'Pelvis, Right Thigh, Right Shank, Right Foot, Left Thigh, Left Shank, Left Foot.'; ''; 'Output files will contain joint angles with 16 columns of data in the following order:'; 'Ankle Left Sagittal, Ankle Left Frontal, Ankle Right Sagittal, Ankle Right Frontal, '; 'Hip Left Sagittal, Hip Left Frontal, Hip Left Transverse, Hip Right Sagittal, Hip RIght Frontal, Hip Right Transverse,'; 'Knee Left Sagittal, Knee Left Frontal, Knee Left Transverse, Knee Right Sagittal, Knee Right Frontal, Knee Right Transverse. '};
        end
    end

    methods (Access = public)

        % Construct app
        function app = mojoxlab_v0_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MoJoXlabv01UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MoJoXlabv01UIFigure)
        end
    end
end